classdef GUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure            matlab.ui.Figure
        DirectionKnobLabel  matlab.ui.control.Label
        DirectionKnob       matlab.ui.control.Knob
        Switch              matlab.ui.control.Switch
        SpectrumOld         matlab.ui.control.UIAxes
        PolarPlot           matlab.ui.control.UIAxes
        AudiogramButton     matlab.ui.control.Button
        PlayButton          matlab.ui.control.Button
        PauseButton         matlab.ui.control.Button
        RestartButton       matlab.ui.control.Button
        SpectrumNew         matlab.ui.control.UIAxes
    end


    properties (Access = private)
        audioInput 
        audioWriter
        audiogram
        Fs double
        buffer
        acquisionTimer
        stop
        frequencies
        audiogramdB
        Dialog
        gain_6000_hz double
    end

    methods (Access = private)
    
        function plotSpectrum(app)
            fprintf('The value of stop in the plot function is: %i \n', app.stop );
            while ((~isDone(app.audioInput))&&(app.stop == false))
                
                app.buffer = app.audioInput();  % Load a frame of audio
                
                output = app.filterFrames();
                
                nNew=length(output);   % create a scaling variable equal to the 
                                        % length of the data    
                xFourier = fft(output-mean(output))/nNew; % fourier transform of the data
                xFourier = 2*xFourier(1:length(output)/2+1); % create a one sided
                                                                % frequency spectrum                                     
                magnitudeFiltered = 20*log10(abs(xFourier));
                freqNew = linspace(0,app.Fs/2,length(output)/2+1);
                
                nOld=length(app.buffer); 
                originalFourier = fft(app.buffer-mean(app.buffer))/nOld;
                originalFourier = 2*originalFourier(1:length(app.buffer)/2+1); 
                magnitudeOriginal = 20*log10(abs(originalFourier));
                freqOld = linspace(0,app.Fs/2,length(app.buffer)/2+1);
                
%     To plot two graphs on the same axis - looks jerky and disconnected
%                 plot(app.SpectrumOld, freqOld, magnitudeOriginal,'r');
%                 drawnow
%                 hold on
%                 plot(app.SpectrumOld, freqNew, magnitudeFiltered, 'b');
%                 drawnow
%                 hold off
                app.audioWriter(output);
                plot(app.SpectrumOld, freqOld, magnitudeOriginal,'b');
                drawnow
                plot(app.SpectrumNew, freqNew, magnitudeFiltered, 'b');
                drawnow
            end
            
        end
        
        function output = filterFrames(app)
            
                 a = filterBand24Function(app.buffer);
                 a = a * app.audiogram(1);
                 b = filterBand25Function(app.buffer);
                 b = b * app.audiogram(2);
                 c = filterBand26Function(app.buffer);
                 c = c * app.audiogram(3);
                 d = filterBand27Function(app.buffer);
                 d = d * app.audiogram(4);
                 e = filterBand28Function(app.buffer);
                 e = e * app.audiogram(5);
                 f = filterBand29Function(app.buffer);
                 f = f * app.audiogram(6);
                 g = filterBand30Function(app.buffer);
                 g = g * app.audiogram(7);
                 h = filterBand31Function(app.buffer);
                 h = h * app.audiogram(8);
                 i = filterBand32Function(app.buffer);
                 i = i * app.audiogram(9);
                 j = filterBand33Function(app.buffer);
                 j = j * app.audiogram(10);
                 k = filterBand34Function(app.buffer);
                 k = k * app.audiogram(11);
                 l = filterBand35Function(app.buffer);
                 l = l * app.audiogram(12);
                 m = filterBand36Function(app.buffer);
                 m = m * app.audiogram(13);
                 n = filterBand37Function(app.buffer);
                 n = n * app.audiogram(14);
                 o = filterBand38Function(app.buffer);
                 o = o * app.audiogram(15);
                 p = filterBand39Function(app.buffer);
                 p = p * app.audiogram(16);
                
            
                 output = a+ b+ c+ d +e+ f+ g+ h+ i+ j+ k+ l+ m+ n+ o+ p;
            
        end
        
    end

    methods (Access = public)
    
        function updateAudiogram(app, values)
            
            requiredFrequencies = linspace(1, 8*10^3, 8*10^3);
            vq = interp1(app.frequencies,values,requiredFrequencies);
            
            app.audiogramdB = [vq(250), vq(315), vq(400), vq(500), vq(630), vq(800), ... 
            vq(1000), vq(1250), vq(1600), vq(2000), vq(2500), vq(3150), ...   
            vq(4000), vq(5000), vq(6300), vq(8000)];
        
            app.gain_6000_hz = vq(6000);
        
            app.audiogram=zeros(1,16);
            
            for i=1: length(app.audiogramdB)
                app.audiogram(i) = db2mag(app.audiogramdB(i));
            end
            
            app.AudiogramButton.Enable = 'on';
            
        end
        
    end


    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.audioInput = dsp.AudioFileReader('Filename', 'B_eng_f1.wav');  
            app.audioWriter = audioDeviceWriter('SampleRate',app.audioInput.SampleRate);
            release(app.audioInput);       % Close input file
            release(app.audioWriter);               % Close audio output device
            app.frequencies = [250 500 1000 2000 4000 6000 8000];
            app.audiogramdB = [0 0 0 0 0 0 0 0 0 0 0 0 0 0 0 0];
            app.audiogram = [1 1 1 1 1 1 1 1 1 1 1 1 1 1 1 1];
            
            app.Fs = 40000;
            
            app.gain_6000_hz = 0;
            
            app.SpectrumOld.XLim = [100 8000];
            app.SpectrumOld.YLim = [-110 2];
            app.SpectrumNew.XLim = [100 8000];
            app.SpectrumNew.YLim = [-110 2];
            
            app.stop = true;
           
        end

        % Button pushed function: PlayButton
        function PlayButtonPushed(app, event)
            app.stop = false;
            fprintf('Play selected \n');
            app.plotSpectrum();
        end

        % Button pushed function: PauseButton
        function PauseButtonPushed(app, event)
            app.stop = true;
            fprintf('Stop selected \n');
            app.plotSpectrum();
        end

        % Button pushed function: RestartButton
        function RestartButtonPushed(app, event)
            release(app.audioInput);       % Close input file
            release(app.audioWriter);      % Close audio output device
            app.stop = false;
            fprintf('Restart selected \n');
            app.plotSpectrum();
        end

        % Button pushed function: AudiogramButton
        function AudiogramButtonPushed(app, event)
            % Disable Plot Options button while dialog is open
            app.AudiogramButton.Enable = 'off';
            % Open the options dialog and pass inputs
            app.Dialog = AudiogramDialog(app, app.audiogramdB, app.gain_6000_hz);
            
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            delete(app.Dialog);
            delete(app);
            
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.DirectionKnobLabel = uilabel(app.UIFigure);
            app.DirectionKnobLabel.Text = 'Direction';
            app.DirectionKnob = uiknob(app.UIFigure, 'continuous');
            app.DirectionKnob.Limits = [0 315];
            app.Switch = uiswitch(app.UIFigure, 'slider');
            app.Switch.Items = {'Omni-Directional', 'Directional'};
            app.Switch.Value = 'Omni-Directional';
            app.SpectrumOld = uiaxes(app.UIFigure);
            title(app.SpectrumOld, 'Original Spectrum')
            xlabel(app.SpectrumOld, 'Frequency (Hz)')
            ylabel(app.SpectrumOld, 'Magnitude (dB)')
            app.PolarPlot = uiaxes(app.UIFigure);
            title(app.PolarPlot, 'Polar Plot')
            xlabel(app.PolarPlot, 'X')
            ylabel(app.PolarPlot, 'Y')
            app.AudiogramButton = uibutton(app.UIFigure, 'push');
            app.AudiogramButton.ButtonPushedFcn = createCallbackFcn(app, @AudiogramButtonPushed, true);
            app.AudiogramButton.Text = 'Audiogram';
            app.PlayButton = uibutton(app.UIFigure, 'push');
            app.PlayButton.ButtonPushedFcn = createCallbackFcn(app, @PlayButtonPushed, true);
            app.PlayButton.Text = 'Play';
            app.PauseButton = uibutton(app.UIFigure, 'push');
            app.PauseButton.ButtonPushedFcn = createCallbackFcn(app, @PauseButtonPushed, true);
            app.PauseButton.Text = 'Pause';
            app.RestartButton = uibutton(app.UIFigure, 'push');
            app.RestartButton.ButtonPushedFcn = createCallbackFcn(app, @RestartButtonPushed, true);
            app.RestartButton.Text = 'Restart';
            app.SpectrumNew = uiaxes(app.UIFigure);
            title(app.SpectrumNew, 'Amplified Spectrum')
            xlabel(app.SpectrumNew, 'Frequency (Hz)')
            ylabel(app.SpectrumNew, 'Magnitude (dB)')
        end
    end

    methods (Access = public)

        % Construct app
        function app = GUI

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end