classdef AudiogramDialog < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        OptionsUIFigure          matlab.ui.Figure
        SaveAudiogramButton      matlab.ui.control.Button
        HzLabel_9                matlab.ui.control.Label
        AudiogramEditField_250   matlab.ui.control.NumericEditField
        HzLabel_10               matlab.ui.control.Label
        AudiogramEditField_500   matlab.ui.control.NumericEditField
        HzLabel_11               matlab.ui.control.Label
        AudiogramEditField_1000  matlab.ui.control.NumericEditField
        HzLabel_12               matlab.ui.control.Label
        AudiogramEditField_2000  matlab.ui.control.NumericEditField
        HzLabel_14               matlab.ui.control.Label
        AudiogramEditField_4000  matlab.ui.control.NumericEditField
        HzLabel_15               matlab.ui.control.Label
        AudiogramEditField_6000  matlab.ui.control.NumericEditField
        HzLabel_16               matlab.ui.control.Label
        AudiogramEditField_8000  matlab.ui.control.NumericEditField
        EntertheaudiogramairthresholdsindBLabel  matlab.ui.control.Label
    end


    properties (Access = private)
        CallingApp   % Main app
        audiogramValues

    end


    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainapp, audiogram, gain6000)
            % Store main app in property for CloseRequestFcn to use
            app.CallingApp = mainapp;
           % app.audiogramValues = audiogram;
           app.AudiogramEditField_250.Value = audiogram(1);
           app.AudiogramEditField_500.Value = audiogram(4);
           app.AudiogramEditField_1000.Value = audiogram(7);
           app.AudiogramEditField_2000.Value = audiogram(10);
           app.AudiogramEditField_4000.Value = audiogram(13);
           app.AudiogramEditField_6000.Value = gain6000;
           app.AudiogramEditField_8000.Value = audiogram(16);
            

        end

        % Callback function: OptionsUIFigure, SaveAudiogramButton
        function OptionsUIFigureCloseRequest(app, event)
            % Send values back to main window by calling its public function
            app.audiogramValues = [app.AudiogramEditField_250.Value ...
                app.AudiogramEditField_500.Value app.AudiogramEditField_1000.Value ...
                app.AudiogramEditField_2000.Value ...
                app.AudiogramEditField_4000.Value app.AudiogramEditField_6000.Value ...
                app.AudiogramEditField_8000.Value];
            updateAudiogram(app.CallingApp, app.audiogramValues);

            % delete options dialog
            delete(app)
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.OptionsUIFigure = uifigure;
            app.OptionsUIFigure.Name = 'Options';
            app.OptionsUIFigure.CloseRequestFcn = createCallbackFcn(app, @OptionsUIFigureCloseRequest, true);
            app.SaveAudiogramButton = uibutton(app.OptionsUIFigure, 'push');
            app.SaveAudiogramButton.ButtonPushedFcn = createCallbackFcn(app, @OptionsUIFigureCloseRequest, true);
            app.SaveAudiogramButton.Text = 'Save Audiogram';
            app.HzLabel_9 = uilabel(app.OptionsUIFigure);
            app.HzLabel_9.Text = '250 Hz';
            app.AudiogramEditField_250 = uieditfield(app.OptionsUIFigure, 'numeric');
            app.HzLabel_10 = uilabel(app.OptionsUIFigure);
            app.HzLabel_10.Text = '500 Hz';
            app.AudiogramEditField_500 = uieditfield(app.OptionsUIFigure, 'numeric');
            app.AudiogramEditField_500.RoundFractionalValues = 'on';
            app.HzLabel_11 = uilabel(app.OptionsUIFigure);
            app.HzLabel_11.Text = '1000 Hz';
            app.AudiogramEditField_1000 = uieditfield(app.OptionsUIFigure, 'numeric');
            app.HzLabel_12 = uilabel(app.OptionsUIFigure);
            app.HzLabel_12.Text = '2000 Hz';
            app.AudiogramEditField_2000 = uieditfield(app.OptionsUIFigure, 'numeric');
            app.HzLabel_14 = uilabel(app.OptionsUIFigure);
            app.HzLabel_14.Text = '4000 Hz';
            app.AudiogramEditField_4000 = uieditfield(app.OptionsUIFigure, 'numeric');
            app.HzLabel_15 = uilabel(app.OptionsUIFigure);
            app.HzLabel_15.Text = '6000 Hz';
            app.AudiogramEditField_6000 = uieditfield(app.OptionsUIFigure, 'numeric');
            app.HzLabel_16 = uilabel(app.OptionsUIFigure);
            app.HzLabel_16.Text = '8000 Hz';
            app.AudiogramEditField_8000 = uieditfield(app.OptionsUIFigure, 'numeric');
            app.EntertheaudiogramairthresholdsindBLabel = uilabel(app.OptionsUIFigure);
            app.EntertheaudiogramairthresholdsindBLabel.Text = 'Enter the audiogram air thresholds in dB';
        end
    end

    methods (Access = public)

        % Construct app
        function app = AudiogramDialog(varargin)

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.OptionsUIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.OptionsUIFigure)
        end
    end
end