classdef GUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        TabGroup                      matlab.ui.container.TabGroup
        CompensatoryAmplificationTab  matlab.ui.container.Tab
        AudiogramButton               matlab.ui.control.Button
        PlayButton                    matlab.ui.control.Button
        PauseButton                   matlab.ui.control.Button
        RestartButton                 matlab.ui.control.Button
        SpectrumOld                   matlab.ui.control.UIAxes
        SpectrumNew                   matlab.ui.control.UIAxes
        DirectionalityTab             matlab.ui.container.Tab
        PolarPlot                     matlab.ui.control.UIAxes
        DirectionKnobLabel            matlab.ui.control.Label
        DirectionKnob                 matlab.ui.control.Knob
        Switch                        matlab.ui.control.Switch
        PlayButtonDirectionality      matlab.ui.control.Button
        PauseButtonDirectionality     matlab.ui.control.Button
        RestartButtonDirectionality   matlab.ui.control.Button
    end


    properties (Access = private)
        audioInput 
        audioWriter
        audiogram
        Fs double
        buffer
        acquisionTimer
        stop
        frequencies
        audiogramdB
        Dialog
        gain_6000_hz double
        oneThirdOctaveFilterBank
        directionalityFlag
        stopDir
        collector
        array
        angleTone
        weightTableTimeDelay
        selectedRow
        NSampPerFrame
        NSampPerFrameAmp
        NSampPerFrameDir
        F0
        nMics
        steervec
        simulatedAngle
        angle
        straightLine
        guiDataX
        guiDataY
        omniDataX
        omniDataY
    end

    methods (Access = private)
    
        function plotSpectrum(app)
            fprintf('The value of stop in the plot function is: %i \n', app.stop );
            while ((~isDone(app.audioInput))&&(app.stop == false))
                
                app.buffer = app.audioInput();  % Load a frame of audio
                
                output = app.filterFrames();
                
                nNew=length(output);   % create a scaling variable equal to the 
                                        % length of the data    
                xFourier = fft(output)/nNew; % fourier transform of the data
                xFourier = 2*xFourier(1:length(output)/2+1); % create a one sided
                                                                % frequency spectrum                                     
                magnitudeFiltered = 20*log10(abs(xFourier));
                freqNew = linspace(0,app.Fs/2,length(output)/2+1);
                
                nOld=length(app.buffer); 
                originalFourier = fft(app.buffer)/nOld;
                originalFourier = 2*originalFourier(1:length(app.buffer)/2+1); 
                magnitudeOriginal = 20*log10(abs(originalFourier));
                freqOld = linspace(0,app.Fs/2,length(app.buffer)/2+1);
                
%     To plot two graphs on the same axis - looks jerky and disconnected
%                 plot(app.SpectrumOld, freqOld, magnitudeOriginal,'r');
%                 hold on
%                 plot(app.SpectrumOld, freqNew, magnitudeFiltered, 'b');
%                 drawnow
%                 hold off
             
                app.audioWriter(output);
                pause(0.005);
                plot(app.SpectrumOld, freqOld, magnitudeOriginal,'b');
                plot(app.SpectrumNew, freqNew, magnitudeFiltered, 'b');
                drawnow

            end
            
        end
        
        function output = filterFrames(app)
             a = app.oneThirdOctaveFilterBank{1}(app.buffer);
             a = a * app.audiogram(1);
             b = app.oneThirdOctaveFilterBank{2}(app.buffer);
             b = b * app.audiogram(2);
             c = app.oneThirdOctaveFilterBank{3}(app.buffer);
             c = c * app.audiogram(3);
             d = app.oneThirdOctaveFilterBank{4}(app.buffer);
             d = d * app.audiogram(4);
             e = app.oneThirdOctaveFilterBank{5}(app.buffer);
             e = e * app.audiogram(5);
             f = app.oneThirdOctaveFilterBank{6}(app.buffer);
             f = f * app.audiogram(6);
             g = app.oneThirdOctaveFilterBank{7}(app.buffer);
             g = g * app.audiogram(7);
             h = app.oneThirdOctaveFilterBank{8}(app.buffer);
             h = h * app.audiogram(8);
             i = app.oneThirdOctaveFilterBank{9}(app.buffer);
             i = i * app.audiogram(9);
             j = app.oneThirdOctaveFilterBank{10}(app.buffer);
             j = j * app.audiogram(10);
             k = app.oneThirdOctaveFilterBank{11}(app.buffer);
             k = k * app.audiogram(11);
             l = app.oneThirdOctaveFilterBank{12}(app.buffer);
             l = l * app.audiogram(12);
             m = app.oneThirdOctaveFilterBank{13}(app.buffer);
             m = m * app.audiogram(13);
             n = app.oneThirdOctaveFilterBank{14}(app.buffer);
             n = n * app.audiogram(14);
             o = app.oneThirdOctaveFilterBank{15}(app.buffer);
             o = o * app.audiogram(15);
             p = app.oneThirdOctaveFilterBank{16}(app.buffer);
             p = p * app.audiogram(16);
             
             output = a+ b+ c+ d +e+ f+ g+ h+ i+ j+ k+ l+ m+ n+ o+ p;
            
        end
        
        function createOneThirdOctaveFilters(app, N)
            BW = '1/3 octave'; 
            F = 1000;
            oneThirdOctaveFilter = octaveFilter('FilterOrder', N, ...
                'CenterFrequency', F, 'Bandwidth', BW, 'SampleRate', app.Fs);
            app.F0 = getANSICenterFrequencies(oneThirdOctaveFilter);
            app.F0(app.F0<250) = [];
            app.F0(app.F0>8000) = [];
            Nfc = length(app.F0);
            for i=1:Nfc
                app.oneThirdOctaveFilterBank{i} = octaveFilter('FilterOrder', N, ...
                    'CenterFrequency', app.F0(i), 'Bandwidth', BW, 'SampleRate', app.Fs); 
            end
        end
        
        function plotPolar(app)
           result = zeros(app.NSampPerFrame, length(app.F0));
           bandOutput = zeros(app.NSampPerFrame, app.nMics*length(app.F0));
           while ((~isDone(app.audioInput))&&(app.stopDir == false))
               x1 =  app.audioInput();
               temp = app.collector(x1, app.angleTone);
               drawnow;
               if (app.directionalityFlag == true)
                    j=1:app.nMics;
                    for i=0:app.nMics:length(app.F0)*app.nMics-app.nMics
                        filterBand = app.oneThirdOctaveFilterBank{i/app.nMics+1};
                        bandOutput(:,i+j) = filterBand(temp(:, j));
                        filterBand.release();
                        bandOutput(:,i+j) = bandOutput(:,i+j)*app.audiogram(i/app.nMics+1);
                        bandOutput(:,i+j) = delayseq(bandOutput(:,i+j),app.weightTableTimeDelay(app.selectedRow, j),app.Fs);
                    end
                    drawnow;
                    
                    i=1:1:app.NSampPerFrame; 
                    j=app.nMics:app.nMics:length(app.F0)*app.nMics;
                    result(i,j/app.nMics) = bandOutput(i,j-9)+bandOutput(i,j-8)+bandOutput(i,j-7)+bandOutput(i,j-6)+bandOutput(i,j-5)+bandOutput(i,j-4)+bandOutput(i,j-3)+bandOutput(i,j-2)+bandOutput(i,j-1)+bandOutput(i,j);
                    
%                     band = 12;
%                     sv=app.steervec(app.F0(band),app.simulatedAngle);
%                     c = 343;
%                     Ddata= directivity(app.array,app.F0(band),app.angle,'PropagationSpeed',c,'Weights',sv);
%                     plot(app.PolarPlot, app.angle,Ddata)
                    plot(app.PolarPlot, app.guiDataX(:,app.selectedRow),app.guiDataY(:,app.selectedRow))
                    drawnow;
                    playOutput = sum(result,2)/app.nMics;
                    app.audioWriter(playOutput);
                    drawnow;
                    
               else
                    temp = temp(:,1); % only pick the first microphone
                    index = 1;
                    drawnow;
                    for j=1:16
                        filterBand = app.oneThirdOctaveFilterBank{j};
                        bandOutput(:,j) = filterBand(temp);
                        filterBand.release();
                        bandOutput(:,j) = bandOutput(:,j)*app.audiogram(index);
                    end
                    %plot(app.PolarPlot, app.angle,app.straightLine)
                    plot(app.PolarPlot, app.omniDataX,app.omniDataY)
                    drawnow;
                    playOutput = sum(bandOutput,2);
                    app.audioWriter(playOutput);
                    drawnow;
               end 
           end
           release(app.audioWriter);
           
        end
        
    end

    methods (Access = public)
    
        function updateAudiogram(app, values)
            
            requiredFrequencies = linspace(1, 8*10^3, 8*10^3);
            vq = interp1(app.frequencies,values,requiredFrequencies);
            
            app.audiogramdB = [vq(250), vq(315), vq(400), vq(500), vq(630), vq(800), ... 
            vq(1000), vq(1250), vq(1600), vq(2000), vq(2500), vq(3150), ...   
            vq(4000), vq(5000), vq(6300), vq(8000)];
        
            app.gain_6000_hz = vq(6000);
        
            app.audiogram=zeros(1,16);
            
            for i=1: length(app.audiogramdB)
                app.audiogram(i) = db2mag(app.audiogramdB(i));
            end
            
            app.AudiogramButton.Enable = 'on';
            
        end
        
    end


    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.NSampPerFrame = 1500;
            app.NSampPerFrameDir = 4500;
            app.NSampPerFrameAmp = 1500;
            app.audioInput = dsp.AudioFileReader('Filename', 'Audio/mergedAudio.wav','SamplesPerFrame',app.NSampPerFrame);  
            app.audioWriter = audioDeviceWriter('SampleRate',app.audioInput.SampleRate);
            release(app.audioInput);       % Close input file
            release(app.audioWriter);               % Close audio output device
            app.frequencies = [250 500 1000 2000 4000 6000 8000];
            app.audiogramdB = [0 0 0 0 0 0 0 0 0 0 0 0 0 0 0 0];
            app.audiogram = [1 1 1 1 1 1 1 1 1 1 1 1 1 1 1 1];
            app.simulatedAngle = 0;
            app.Fs = 40000;
            
            app.gain_6000_hz = 0;
            app.angle=0:1:180;
            app.straightLine = zeros(1,181);
            app.PolarPlot.XLim = [-1.2 1.2];
            app.PolarPlot.YLim = [0 1.2];
            app.PolarPlot.YTickLabel={'0','0.2','0.4','0.6','0.8','1.0',' '};
            app.PolarPlot.XTick=[-1.2 -1 -0.8 -0.6 -0.4 -0.2 0 0.2 0.4 0.6 0.8 1 1.2];
            app.PolarPlot.XTickLabel={' ','1.0','0.8','0.6','0.4','0.2','0','0.2','0.4','0.6','0.8','1.0',' '};
            
            %xticklabels({'1.0','0.8','0.6','0.4','0.2','0','0.2','0.4','0.6','0.8','1.0'})
            app.SpectrumOld.YLim = [-50 2];
            
            app.SpectrumOld.XLim = [100 8000];
            app.SpectrumOld.YLim = [-110 2];
            app.SpectrumNew.XLim = [100 8000];
            app.SpectrumNew.YLim = [-110 2];
            app.stop = true;
            order = 14;
            app.createOneThirdOctaveFilters(order);
            
            app.directionalityFlag = false;
            app.stopDir = true;
            
            d=5*10^-2;
            microphone = phased.OmnidirectionalMicrophoneElement('FrequencyRange',[20 8e3],'BackBaffled',true);
            app.nMics = 10; %no of microphones
            app.array = phased.ULA(app.nMics,d,'Element',microphone,'ArrayAxis','x');
            c = 343; %speed of sound
            app.collector=phased.WidebandCollector('Sensor',app.array,'PropagationSpeed',c,...
            'SampleRate',app.Fs,'NumSubbands',8000,'ModulatedInput',...
            false);
            app.steervec = phased.SteeringVector('SensorArray',app.array,'PropagationSpeed',c);
            app.angleTone=[0 ; 0];
            
            lambda = 343./app.F0;
           
            theta=0:(1/18)*pi:pi;
            
            phaseDelay = zeros(16, length(theta));
            for i=1:length(app.F0)
            phaseDelay(i,:) = -(2*pi*(d/lambda(i)))*cos(theta);
            end
            
            weightTableAngles = zeros(length(phaseDelay),app.nMics*16); % 16 filters
            
            index = 1;
            for counter = 0:app.nMics:length(app.F0)*app.nMics-app.nMics
                for r=1:length(theta)
                    for microphone=app.nMics:-1:1
                       weightTableAngles(r,app.nMics+1-microphone+counter)= phaseDelay(index, r)*(microphone-1);
                    end  
                end
                index = index +1;
            end
            index2 =1;
            app.weightTableTimeDelay = zeros(length(phaseDelay),app.nMics);
            
            for r=1:length(theta)
                for microphone=app.nMics:-1:1
                    fmid = app.F0(index2);
                    app.weightTableTimeDelay(r,app.nMics+1-microphone)= weightTableAngles(r,app.nMics+1-microphone)/(fmid*2*pi);
                end
            end
            app.selectedRow = 1;
            
            band=12;
            
            guiData = zeros(length(app.angle),length(theta));
            for i=1:length(theta)
                guiSteerVector=app.steervec(app.F0(band),rad2deg(theta(i)));
                temp=directivity(app.array,app.F0(band),app.angle,'PropagationSpeed',c,'Weights',guiSteerVector);
                for j=1:1:length(app.angle)
                    guiData(j,i)=temp(j);
                end
            end
            
            guiDataMag=db2mag(guiData);

            normalised = zeros(length(app.angle),length(theta));
            maximum=max(guiDataMag);
            %minimum=min(guiDataMag);
            for i=1:length(theta)
                for j=1:1:length(app.angle)
                    %normalised(j,i)=(guiData(j,i)-minimum(i))/(maximum(i)-minimum(i));
                    normalised(j,i)=(guiDataMag(j,i))/(maximum(i));
                end
            end
            
            app.guiDataX=zeros(length(app.angle),length(theta));
            app.guiDataY=zeros(length(app.angle),length(theta));

            for i=1:length(theta)
                for j=1:1:length(app.angle)
                    app.guiDataX(j,i)= normalised(j,i)*cos(deg2rad(app.angle(j)));
                end
            end

            for i=1:length(theta)
                for j=1:1:length(app.angle)
                    app.guiDataY(j,i)= normalised(j,i)*sin(deg2rad(app.angle(j)));
                end
            end
            
            app.omniDataX=zeros(1,length(app.angle));
            app.omniDataY=zeros(1,length(app.angle));
            for i=1:length(app.angle)
                app.omniDataX(1,i)=cos(deg2rad(app.angle(i)));
            end
            for i=1:length(app.angle)
                app.omniDataY(1,i)=sin(deg2rad(app.angle(i)));
            end
           
        end

        % Button pushed function: PlayButton
        function PlayButtonPushed(app, event)
            app.stop = false;
            fprintf('Play selected \n');
            if isDone(app.audioInput)
                release(app.audioInput);       % Close input file
                release(app.audioWriter);      % Close audio output device
            end
            app.plotSpectrum();
        end

        % Button pushed function: PauseButton
        function PauseButtonPushed(app, event)
            app.stop = true;
            fprintf('Stop selected \n');
        end

        % Button pushed function: RestartButton
        function RestartButtonPushed(app, event)
            release(app.audioInput);       % Close input file
            release(app.audioWriter);      % Close audio output device
            app.stop = false;
            fprintf('Restart selected \n');
            app.plotSpectrum();
        end

        % Button pushed function: AudiogramButton
        function AudiogramButtonPushed(app, event)
            % Disable Plot Options button while dialog is open
            app.AudiogramButton.Enable = 'off';
            % Open the options dialog and pass inputs
            app.Dialog = AudiogramDialog(app, app.audiogramdB, app.gain_6000_hz);
            
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            delete(app.Dialog);
            delete(app);
        end

        % Value changed function: Switch
        function SwitchValueChanged(app, event)
            value = app.Switch.Value;
            app.stopDir = false;
            if value == 48
                app.directionalityFlag = false;
            elseif value == 49
                app.directionalityFlag = true;
            end
        end

        % Value changed function: DirectionKnob
        function DirectionKnobValueChanged(app, event)
            value = app.DirectionKnob.Value;
            if app.directionalityFlag == true
                if value >= -180 && value <= 0 % only do stuff if the direction is 0 to 180 degrees
                    app.selectedRow = round(-value/10)+1;
                    app.simulatedAngle = -value;
                elseif value < -180
                    app.selectedRow = 19;
                    app.simulatedAngle = 180;
                else
                    app.selectedRow = 1;
                    app.simulatedAngle = 0;
                end
            end
        end

        % Value changing function: DirectionKnob
        function DirectionKnobValueChanging(app, event)
            changingValue = event.Value;
            if app.directionalityFlag == true
                if changingValue >= -180 && changingValue <= 0 % only do stuff if the direction is 0 to 180 degrees
                    app.selectedRow = round(-changingValue/10)+1;
                    app.simulatedAngle = -changingValue;
                elseif changingValue < -180
                    app.selectedRow = 19;
                    app.simulatedAngle = 180;
                else
                    app.selectedRow = 1;
                    app.simulatedAngle = 0;
                end
            end   
        end

        % Button pushed function: PlayButtonDirectionality
        function PlayButtonDirectionalityPushed(app, event)
            app.stopDir = false;
            fprintf('Play direction selected \n');
            if isDone(app.audioInput)
                release(app.audioInput);       % Close input file
                release(app.audioWriter);      % Close audio output device
            end
            app.plotPolar();
        end

        % Selection change function: TabGroup
        function TabGroupSelectionChanged(app, event)
            selectedTab = app.TabGroup.SelectedTab;
            fprintf('Selection is %s \n' , selectedTab.Title);
            release(app.audioInput);
            release(app.audioWriter);
            if selectedTab.Title=="Directionality"
                app.NSampPerFrame = app.NSampPerFrameDir;
                app.audioInput = dsp.AudioFileReader('Filename', 'Audio/mergedAudio.wav','SamplesPerFrame',app.NSampPerFrame);  
                app.stop = true;
            elseif selectedTab.Title=="Compensatory Amplification"
                app.NSampPerFrame = app.NSampPerFrameAmp;
                app.audioInput = dsp.AudioFileReader('Filename', 'Audio/mergedAudio.wav','SamplesPerFrame',app.NSampPerFrame); 
                app.stopDir = true;
            end
            release(app.audioInput);       % Close input file
            release(app.audioWriter);      % Close audio output device
        end

        % Button pushed function: PauseButtonDirectionality
        function PauseButtonDirectionalityPushed(app, event)
            app.stopDir = true;
            fprintf('Stop selected: stopDir = %i \n', app.stopDir);
        end

        % Button pushed function: RestartButtonDirectionality
        function RestartButtonDirectionalityPushed(app, event)
            release(app.audioInput);       % Close input file
            release(app.audioWriter);      % Close audio output device
            app.stopDir = false;
            fprintf('Restart selected \n');
            app.plotPolar();
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.TabGroup = uitabgroup(app.UIFigure);
            app.TabGroup.SelectionChangedFcn = createCallbackFcn(app, @TabGroupSelectionChanged, true);
            app.CompensatoryAmplificationTab = uitab(app.TabGroup);
            app.CompensatoryAmplificationTab.Title = 'Compensatory Amplification';
            app.AudiogramButton = uibutton(app.CompensatoryAmplificationTab, 'push');
            app.AudiogramButton.ButtonPushedFcn = createCallbackFcn(app, @AudiogramButtonPushed, true);
            app.AudiogramButton.Text = 'Audiogram';
            app.PlayButton = uibutton(app.CompensatoryAmplificationTab, 'push');
            app.PlayButton.ButtonPushedFcn = createCallbackFcn(app, @PlayButtonPushed, true);
            app.PlayButton.Text = 'Play';
            app.PauseButton = uibutton(app.CompensatoryAmplificationTab, 'push');
            app.PauseButton.ButtonPushedFcn = createCallbackFcn(app, @PauseButtonPushed, true);
            app.PauseButton.Text = 'Pause';
            app.RestartButton = uibutton(app.CompensatoryAmplificationTab, 'push');
            app.RestartButton.ButtonPushedFcn = createCallbackFcn(app, @RestartButtonPushed, true);
            app.RestartButton.Text = 'Restart';
            app.SpectrumOld = uiaxes(app.CompensatoryAmplificationTab);
            title(app.SpectrumOld, 'Original Spectrum')
            xlabel(app.SpectrumOld, 'Frequency (Hz)')
            ylabel(app.SpectrumOld, 'Magnitude (dB)')
            app.SpectrumNew = uiaxes(app.CompensatoryAmplificationTab);
            title(app.SpectrumNew, 'Amplified Spectrum')
            xlabel(app.SpectrumNew, 'Frequency (Hz)')
            ylabel(app.SpectrumNew, 'Magnitude (dB)')
            app.DirectionalityTab = uitab(app.TabGroup);
            app.DirectionalityTab.Title = 'Directionality';
            app.PolarPlot = uiaxes(app.DirectionalityTab);
            title(app.PolarPlot, 'Polar Plot')
            xlabel(app.PolarPlot, 'X Axis Normalized Gain (linear)')
            ylabel(app.PolarPlot, 'Y Axis Normalized Gain (linear)')
            app.DirectionKnobLabel = uilabel(app.DirectionalityTab);
            app.DirectionKnobLabel.Text = 'Direction';
            app.DirectionKnob = uiknob(app.DirectionalityTab, 'continuous');
            app.DirectionKnob.Limits = [-210 30];
            app.DirectionKnob.MajorTicks = [-210 -180 -150 -120 -90 -60 -30 0 30];
            app.DirectionKnob.MajorTickLabels = {'', '180', '150', '120', '90', '60', '30', '0', ''};
            app.DirectionKnob.ValueChangedFcn = createCallbackFcn(app, @DirectionKnobValueChanged, true);
            app.DirectionKnob.ValueChangingFcn = createCallbackFcn(app, @DirectionKnobValueChanging, true);
            app.Switch = uiswitch(app.DirectionalityTab, 'slider');
            app.Switch.Items = {'Omni-Directional', 'Directional'};
            app.Switch.ItemsData = {'0', '1'};
            app.Switch.ValueChangedFcn = createCallbackFcn(app, @SwitchValueChanged, true);
            app.Switch.Value = '0';
            app.PlayButtonDirectionality = uibutton(app.DirectionalityTab, 'push');
            app.PlayButtonDirectionality.ButtonPushedFcn = createCallbackFcn(app, @PlayButtonDirectionalityPushed, true);
            app.PlayButtonDirectionality.Text = 'Play';
            app.PauseButtonDirectionality = uibutton(app.DirectionalityTab, 'push');
            app.PauseButtonDirectionality.ButtonPushedFcn = createCallbackFcn(app, @PauseButtonDirectionalityPushed, true);
            app.PauseButtonDirectionality.Text = 'Pause';
            app.RestartButtonDirectionality = uibutton(app.DirectionalityTab, 'push');
            app.RestartButtonDirectionality.ButtonPushedFcn = createCallbackFcn(app, @RestartButtonDirectionalityPushed, true);
            app.RestartButtonDirectionality.Text = 'Restart';
        end
    end

    methods (Access = public)

        % Construct app
        function app = GUI

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end